classdef Laserheating_original < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ThermaldiffusionlengthUIFigure  matlab.ui.Figure
        ThermalDiffusionLengthmLabel    matlab.ui.control.Label
        L                               matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        CalculateButton                 matlab.ui.control.Button
        MaterialThicknessnmSliderLabel  matlab.ui.control.Label
        MaterialThicknessnmSlider       matlab.ui.control.Slider
        UIAxes2                         matlab.ui.control.UIAxes
        ElementDropDownLabel            matlab.ui.control.Label
        ElementDropDown                 matlab.ui.control.DropDown
        MaxDeltaTKLabel                 matlab.ui.control.Label
        maxT                            matlab.ui.control.NumericEditField
        PenetrationDepthmLabel          matlab.ui.control.Label
        lit                             matlab.ui.control.NumericEditField
        DeltaTonSurfaceKLabel           matlab.ui.control.Label
        DeltaT                          matlab.ui.control.NumericEditField
        ThicknessforMaxDeltaTmLabel     matlab.ui.control.Label
        maxTz                           matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            %Laser properties            
                        
            E = 680e-6;
            durchmesser = (2.7e-3)^2*3.1415;
            %E = 1e-3
            %durchmesser = 1e-4
            
            t = 25e-12;
            I0 = E/(durchmesser*t);
            z = 0:1e-11:app.MaterialThicknessnmSlider.Value*1e-9;
            
            
            %Glass Properties
            
            d_glass = 2550; %[kg/m^3]
            C_glass = 860; %[J/(kg*K)]
            V_glass = durchmesser*20e-9; %[m^3]
            E_glass = d_glass*C_glass*V_glass; %[J/K]
            
            %Element Properies
            
            
            value = app.ElementDropDown.Value
            
           if value == 'Ti'
                C = 34;
                a = 7.9156e7;
                app.lit.Value = 1/a;
                d = 4.506;
                M = 47.867;
                k = 21.9;
                app.L.Value = sqrt(4*k/((d/M*1000000)*C)*25e-12);
                R = 0.57501;
                %Trans_50nm = 0.0034507;
                E0 = (1-R)*E
                absorb = E0 - E0 * exp(-a*(app.MaterialThicknessnmSlider.Value*1e-9))
                app.DeltaT.Value = absorb / ((C*(d/M*1000000)*durchmesser*(app.MaterialThicknessnmSlider.Value*1e-9) + E_glass))                
                
                
            elseif value == 'Cu'
                C = 24.44;
                a = 6.131e7;
                d = 8.96;
                app.lit.Value = 1/a;
                M = 63.5;
                k = 401;
                app.L.Value = sqrt(4*k/((d/M*1000000)*C)*25e-12);
                R = 0.60197;
                E0 = (1-R)*E
                absorb = E0 - E0 * exp(-a*(app.MaterialThicknessnmSlider.Value*1e-9))
                app.DeltaT.Value = absorb / ((C*(d/M*1000000)*durchmesser*(app.MaterialThicknessnmSlider.Value*1e-9) + E_glass))
                
            elseif value == 'Cr'
                C = 23.35;
                a = 7.8658e7;
                app.lit.Value = 1/a;
                d = 7.19;
                M = 52;
                k = 93.9;        
                app.L.Value = sqrt(4*k/((d/M*1000000)*C)*25e-12);
                R = 0.55646;
                E0 = (1-R)*E
                absorb = E0 - E0 * exp(-a*(app.MaterialThicknessnmSlider.Value*1e-9))
                app.DeltaT.Value = absorb / ((C*(d/M*1000000)*durchmesser*(app.MaterialThicknessnmSlider.Value*1e-9) + E_glass))
                
            end
            
            absorbvonz = E0 - E0 * exp(-a*z)
            T = absorbvonz ./ ((C*(d/M*1000000)*durchmesser*z + E_glass))
               
            
             app.maxT.Value = max(T)
             app.maxTz.Value = z(T==max(T))
            
            
            %Intensity Calculation
            
            I = I0 * exp(-a*z);
            
            Inorm = I./I0;


            plot(app.UIAxes,z,I)
            
            plot(app.UIAxes2,z,T)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ThermaldiffusionlengthUIFigure = uifigure;
            app.ThermaldiffusionlengthUIFigure.Name = 'Thermal diffusion length';
            setAutoResize(app, app.ThermaldiffusionlengthUIFigure, true)
            app.ThermalDiffusionLengthmLabel = uilabel(app.ThermaldiffusionlengthUIFigure);
            app.ThermalDiffusionLengthmLabel.Text = 'Thermal Diffusion Length [m]';
            app.L = uieditfield(app.ThermaldiffusionlengthUIFigure, 'numeric');
            app.UIAxes = uiaxes(app.ThermaldiffusionlengthUIFigure);
            title(app.UIAxes, 'Intensity vs Thickness');
            xlabel(app.UIAxes, 'z [m]');
            ylabel(app.UIAxes, 'I [W/m^2]');
            app.CalculateButton = uibutton(app.ThermaldiffusionlengthUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MaterialThicknessnmSliderLabel = uilabel(app.ThermaldiffusionlengthUIFigure);
            app.MaterialThicknessnmSliderLabel.Text = 'Material Thickness [nm]';
            app.MaterialThicknessnmSlider = uislider(app.ThermaldiffusionlengthUIFigure);
            app.MaterialThicknessnmSlider.Limits = [0 60];
            app.MaterialThicknessnmSlider.Value = 40;
            app.UIAxes2 = uiaxes(app.ThermaldiffusionlengthUIFigure);
            title(app.UIAxes2, 'Temperature vs Thickness');
            xlabel(app.UIAxes2, 'z [m]');
            ylabel(app.UIAxes2, '\Delta T [K]');
            app.ElementDropDownLabel = uilabel(app.ThermaldiffusionlengthUIFigure);
            app.ElementDropDownLabel.Text = 'Element';
            app.ElementDropDown = uidropdown(app.ThermaldiffusionlengthUIFigure);
            app.ElementDropDown.Items = {'Ti', 'Cu', 'Cr'};
            app.ElementDropDown.Value = 'Cr';
            app.MaxDeltaTKLabel = uilabel(app.ThermaldiffusionlengthUIFigure);
            app.MaxDeltaTKLabel.Text = 'Max Delta T [K]';
            app.maxT = uieditfield(app.ThermaldiffusionlengthUIFigure, 'numeric');
            app.PenetrationDepthmLabel = uilabel(app.ThermaldiffusionlengthUIFigure);
            app.PenetrationDepthmLabel.Text = 'Penetration Depth [m]';
            app.lit = uieditfield(app.ThermaldiffusionlengthUIFigure, 'numeric');
            app.DeltaTonSurfaceKLabel = uilabel(app.ThermaldiffusionlengthUIFigure);
            app.DeltaTonSurfaceKLabel.Text = 'Delta T on Surface [K]';
            app.DeltaT = uieditfield(app.ThermaldiffusionlengthUIFigure, 'numeric');
            app.ThicknessforMaxDeltaTmLabel = uilabel(app.ThermaldiffusionlengthUIFigure);
            app.ThicknessforMaxDeltaTmLabel.Text = 'Thickness for Max Delta T [m]';
            app.maxTz = uieditfield(app.ThermaldiffusionlengthUIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Laserheating_original()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ThermaldiffusionlengthUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ThermaldiffusionlengthUIFigure)
        end
    end
end